classdef poticos < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        VigasPanel                      matlab.ui.container.Panel
        BfmEditFieldLabel               matlab.ui.control.Label
        Bfv                             matlab.ui.control.NumericEditField
        dmEditFieldLabel                matlab.ui.control.Label
        dvv                             matlab.ui.control.NumericEditField
        EMPaEditFieldLabel              matlab.ui.control.Label
        Ecv                             matlab.ui.control.NumericEditField
        ColumnasPanel                   matlab.ui.container.Panel
        BfmEditField_2Label             matlab.ui.control.Label
        Bfc                             matlab.ui.control.NumericEditField
        dmEditField_2Label              matlab.ui.control.Label
        dcc                             matlab.ui.control.NumericEditField
        EMPaEditFieldLabel_2            matlab.ui.control.Label
        Ecc                             matlab.ui.control.NumericEditField
        CargaspuntualesPanel            matlab.ui.container.Panel
        NudosCargaHorizontalEditFieldLabel  matlab.ui.control.Label
        NudosCargaHorizontal            matlab.ui.control.EditField
        CargaskNEditFieldLabel          matlab.ui.control.Label
        CargasX                         matlab.ui.control.EditField
        NudosCargaVertivalEditFieldLabel  matlab.ui.control.Label
        NudosCargaVertival              matlab.ui.control.EditField
        CargaskNEditField_2Label        matlab.ui.control.Label
        CargasY                         matlab.ui.control.EditField
        ApoyosPanel                     matlab.ui.container.Panel
        NudosapoyosEditFieldLabel       matlab.ui.control.Label
        Nudosapoyos                     matlab.ui.control.EditField
        tipodeapoyosEditFieldLabel      matlab.ui.control.Label
        tipodeapoyos                    matlab.ui.control.EditField
        GeometriaButton                 matlab.ui.control.Button
        GeometriaGrillaPanel            matlab.ui.container.Panel
        datosY                          matlab.ui.control.Table
        datosX                          matlab.ui.control.Table
        Actualizar                      matlab.ui.control.Button
        datosXSpinnerLabel              matlab.ui.control.Label
        datosXSp                        matlab.ui.control.Spinner
        datosYSpinnerLabel              matlab.ui.control.Label
        datosYSp                        matlab.ui.control.Spinner
        CalculoButton                   matlab.ui.control.StateButton
        EscalasPanel                    matlab.ui.container.Panel
        EscarlacargaaxialEditFieldLabel  matlab.ui.control.Label
        Escarlacargaaxial               matlab.ui.control.NumericEditField
        EscarlaMomentosEditFieldLabel   matlab.ui.control.Label
        EscarlaMomentos                 matlab.ui.control.NumericEditField
        EscarlacortantesEditFieldLabel  matlab.ui.control.Label
        Escarlacortantes                matlab.ui.control.NumericEditField
        EscarladeformadaEditFieldLabel  matlab.ui.control.Label
        Escarladeformada                matlab.ui.control.NumericEditField
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.datosY.Data=ones(20,1)*5;
            app.datosX.Data=ones(20,1)*5;
        end

        % Button pushed function: GeometriaButton
        function GeometriaButtonPushed(app, event)
                    % se definen algunas constantes que hacen el codigo mas legible
        X = 1;
        Y = 2;
        G = 3;
        XY = 12;
        XYG=123;
        %% Se describen las propiedades de los materiales
        %%% E G
        Esc=app.Ecc.Value;
        Esv=app.Ecv.Value;
        gama=24;
        v=0.25;
        Gsc=Esc/(2*(1+v));
        Gsv=Esv/(2*(1+v));
        EG=[Esv,Gsc;
            Esc,Gsv];%[2,#materiales]];
        
        %% ingreso coordenadas de nudos de estructura % [#coordenadas,2]
        
        x=app.datosX.Data;
        y=app.datosY.Data;
        xa= [0,cumsum(x',2)];
        ya= [0,cumsum(y',2)];
        [Xe,Ye]=meshgrid(xa,ya);%% grilla coordenadas nudos elemento
        Bx = reshape(Xe,[],1);
        By = reshape(Ye,[],1);
        Nx=size(xa,2);
        Ny=size(ya,2);
        %% nudos elemento
        nno  = Nx*Ny; % numero de nodos (numero de filas de xnod)
        nnoi=1:nno;
        xnod=[Bx,By];
        
        %% nugos grilla elemento
        Nudosg = reshape(nnoi,[Ny,Nx]);
        
        nef=(Ny-1)*Nx+(Nx-1)*(Ny-1);
        recorridoy=2:1:Ny;
        recorridox=2:1:Nx;
        
        LaGc = zeros(nef,2); 
        xeg = zeros(nef,2); 
        yeg = zeros(nef,2); 
        recorridoLaG=1:1:nef;
        cg1 = zeros(nef,2); % almacena el centro de gravedad de los EFs
        hold on
        text(Bx, By, num2str(nnoi'), 'Color', 'k');
        e=1;
        %% nudos columnas
        for ey=1:(Nx)
            for ex=1:(Ny-1)
               %%nudos por elemento
               LaGc(e,:)=Nudosg((recorridoy(ex)-1):recorridoy(ex),ey)'; 
               %coordenadas elemento
               xeg(e,:)=Xe((recorridoy(ex)-1):recorridoy(ex),ey)'; 
               yeg(e,:)=Ye((recorridoy(ex)-1):recorridoy(ex),ey)'; 
               cg1(e,:) = [mean(xeg(e,:)),mean(yeg(e,:))];
               text(cg1(e,X), cg1(e,Y), num2str(recorridoLaG(e)), 'Color', 'b');
               plot(xeg(e,:),yeg(e,:))
               e=e+1;
            end
        end
        %% nudso vigas
        %% nudos columnas
        for ey=1:(Nx-1)
            for ex=2:(Ny)
               %%nudos por elemento
               LaGc(e,:)=Nudosg(ex,(recorridox(ey)-1):recorridox(ey)); 
               %coordenadas elemento
               xeg(e,:)=Xe(ex,(recorridox(ey)-1):recorridox(ey)); 
               yeg(e,:)=Ye(ex,(recorridox(ey)-1):recorridox(ey)); 
               cg1(e,:) = [mean(xeg(e,:)),mean(yeg(e,:))];
               text(cg1(e,X), cg1(e,Y), num2str(recorridoLaG(e)), 'Color', 'b');
               plot(xeg(e,:),yeg(e,:))
               e=e+1;
            end
        
        end
        
        axis equal tight
        %grid on
        title('Malla de elementos finitos');
        plot(Xe,Ye, '*r'),hold on%% grafica nodos elemento finito

        end

        % Button pushed function: Actualizar
        function ActualizarPushed(app, event)
        x=app.datosX.Data;
        y=app.datosX.Data;
        xs=app.datosXSp.Value;
        ys=app.datosYSp.Value;
        
        sizex=size(x,1);
        sizey=size(y,1);
        
        if sizex>=xs
            x=x(1:xs,1);
        else
            x=[x(1:sizex,1);ones(xs-sizex,1)*5];
        end    
        if sizey>=ys
            y=y(1:ys,1);
        else
            y=[y(1:sizey,1);ones(ys-sizey,1)*5];
        end
        app.datosX.Data=x;
        app.datosY.Data=y;
        
        end

        % Value changed function: CalculoButton
        function CalculoButtonValueChanged(app, event)
        X = 1;
        Y = 2;
        G = 3;
        XY = 12;
        XYG=123;
        %% Se describen las propiedades de los materiales
        %%% E G
        Esc=app.Ecc.Value;
        Esv=app.Ecv.Value;
        gama=24;
        v=0.25;
        Gsc=Esc/(2*(1+v));
        Gsv=Esv/(2*(1+v));
        EG=[Esc,Gsc;
            Esv,Gsv];%[2,#materiales]];
        
        %% ingreso coordenadas de nudos de estructura % [#coordenadas,2]
        
        x=app.datosX.Data;
        y=app.datosY.Data;
        xa= [0,cumsum(x',2)];
        ya= [0,cumsum(y',2)];
        [Xe,Ye]=meshgrid(xa,ya);%% grilla coordenadas nudos elemento
        Bx = reshape(Xe,[],1);
        By = reshape(Ye,[],1);
        Nx=size(xa,2);
        Ny=size(ya,2);
        %% nudos elemento
        nno  = Nx*Ny; % numero de nodos (numero de filas de xnod)
        nnoi=1:nno;
        xnod=[Bx,By];
        
        %% nugos grilla elemento
        Nudosg = reshape(nnoi,[Ny,Nx]);
        
        nef=(Ny-1)*Nx+(Nx-1)*(Ny-1);
        recorridoy=2:1:Ny;
        recorridox=2:1:Nx;
        
        LaGc = zeros(nef,2); 
        xeg = zeros(nef,2); 
        yeg = zeros(nef,2); 
        recorridoLaG=1:1:nef;
        cg1 = zeros(nef,2); % almacena el centro de gravedad de los EFs
        hold on
        text(Bx, By, num2str(nnoi'), 'Color', 'k');
        e=1;
        %% nudos columnas
        for ey=1:(Nx)
            for ex=1:(Ny-1)
               %%nudos por elemento
               LaGc(e,:)=Nudosg((recorridoy(ex)-1):recorridoy(ex),ey)'; 
               %coordenadas elemento
               xeg(e,:)=Xe((recorridoy(ex)-1):recorridoy(ex),ey)'; 
               yeg(e,:)=Ye((recorridoy(ex)-1):recorridoy(ex),ey)'; 
               cg1(e,:) = [mean(xeg(e,:)),mean(yeg(e,:))];
               text(cg1(e,X), cg1(e,Y), num2str(recorridoLaG(e)), 'Color', 'b');
               plot(xeg(e,:),yeg(e,:))
               e=e+1;
            end
        end
        %% nudso vigas
        %% nudos columnas
        for ey=1:(Nx-1)
            for ex=2:(Ny)
               %%nudos por elemento
               LaGc(e,:)=Nudosg(ex,(recorridox(ey)-1):recorridox(ey)); 
               %coordenadas elemento
               xeg(e,:)=Xe(ex,(recorridox(ey)-1):recorridox(ey)); 
               yeg(e,:)=Ye(ex,(recorridox(ey)-1):recorridox(ey)); 
               cg1(e,:) = [mean(xeg(e,:)),mean(yeg(e,:))];
               text(cg1(e,X), cg1(e,Y), num2str(recorridoLaG(e)), 'Color', 'b');
               plot(xeg(e,:),yeg(e,:))
               e=e+1;
            end
        
        end
        
        axis equal tight
        %grid on
        title('Malla de elementos finitos');
        plot(Xe,Ye, '*r'),hold on%% grafica nodos elemento finito

        %% Nudos que conectan elementos
        ninf=LaGc;
        %% ingreso coordenadas de nudos de estructura % [#coordenadas,2]
        xyc=xnod;
        
        columnas=(Ny-1)*Nx;
        vigas=(Nx-1)*(Ny-1);
        % dimensiones columnas
        
        dc=app.dcc.Value;
        bfc=app.Bfc.Value;
        tw=0;
        tf=0;
        [Aic,Ixic,As2ic] = propiedades_geometrica_perfiles_v1(dc,bfc,tw,tf);
        % dimensiones vigas
        dv=app.dvv.Value;
        bfv=app.Bfv.Value;
        
        [Aiv,Ixiv,As2iv] = propiedades_geometrica_perfiles_v1(dv,bfv,tw,tf);
        sec1=2;
        
        % [m^2 area,% m^4 inercia_y,% m^2 area cortante];
        %A  I AC  [3 columnas,numero de secciones]
        sec = [Aic(sec1),Ixic(sec1),As2ic(sec1); % seccion 1  columnas
               Aiv(sec1),Ixiv(sec1),As2iv(sec1)];% seccion 2    vigas
        %% tipo de sección para cada elemento
        sec_ele = [ones(columnas,1);ones(vigas,1)*2];
        
        %% cargas aplicadas distribuida (gdl carga)
        % wix   , wfx , direccion   [#cargas,4 columnas]%
        %[kN/m ,kN/m,elemeto cargado,direccion X   ] 
        %  para cargas proyectadas cos cargas verticales
        %  para cargas proyectadas sen cargas horizontales
        
        wic=Aic(sec1,1)*gama;
        wiv=Aiv(sec1,1)*gama;
        wc=ones(columnas,1)*wic;
        wv=ones(vigas,1)*wiv;
        w=[wc;wv];
        nele=(1:nef)';
        
        elemento_carga=[-w  ,-w,nele ,ones(nef,1)*2];
        
        %% carga puntual 
        % P,nudo,direccion X Y G
        %% nudos apoyos
        %% Nudos cargas Horizontales
        NudosCargaHorizontala=(app.NudosCargaHorizontal.Value);
        sizecolumnaa=size(NudosCargaHorizontala,2);
        nudosacargash=str2num(NudosCargaHorizontala(2:(sizecolumnaa-1))); 
         %% carga en direccion X
        CargasXa=(app.CargasX.Value);
        sizecolumnaa=size(CargasXa,2);
        cargasX=str2num(CargasXa(2:(sizecolumnaa-1))); 
        %% Nudos cargas Verticales
        NudosCargaVertivala=(app.NudosCargaVertival.Value);
        sizecolumnaa=size(NudosCargaVertivala,2);
        nudosacargasv=str2num(NudosCargaVertivala(2:(sizecolumnaa-1))); 
        %% carga en direccion Y
        CargasYa=(app.CargasY.Value);
        sizecolumnaa=size(CargasYa,2);
        cargasY=str2num(CargasYa(2:(sizecolumnaa-1))); 
        Ca=[cargasX';cargasY'];
        nuc=[nudosacargash';nudosacargasv'];
        
        sizeX=size(cargasX,2);
        sizeY=size(cargasY,2);
        dir=[ones(sizeX,1);ones(sizeX,1)*2];
        nudo_carga=[Ca,nuc,dir]; 
                 
        %% tipo de apoyo 
        % retriccion,nudo
        %%X horizontal, Y vertical, XY horozonal y vertical, XYG horizontal vertica y giro
        NApoypc=(app.Nudosapoyos.Value);
        sizecolumnaa=size(NApoypc,2);
        NApoypcc=str2num(NApoypc(2:(sizecolumnaa-1))); 
        
        Apoypc=(app.tipodeapoyos.Value);
        sizecolumnaa=size(Apoypc,2);
        Apoypcc=str2num(Apoypc(2:(sizecolumnaa-1)));
        
        
        tipo_apoyo=[Apoypcc',NApoypcc'];
        %% X resorte, resorte Y  resorte G
        % reigidez resoporte,nudo,direccion X Y G
        nudo_resorte=[0,1,Y];
                      %0,3,Y];   
        %% tipo de continuidad
        %E empotrado
        %R Rotula
        % EE , ER, RE, RR
        %% CON RESORTE
        %kw
        %% con nudos rigidos
        % NR
        tipo_conti=char(ones(columnas+vigas,2).*[69,69]);%% coeficioente de balastro para elemetos apoyado sobre lecho elastico        
        %% coeficioente de balastro para elemetos apoyado sobre lecho elastico        
        kw= zeros(nef,1);
        PD='no';
        %% longitud de nudo rigido
        lalb=zeros(columnas+vigas,2);
        %% tipo de material por elemento 
        mat_elem = [ones(1,columnas),ones(1,vigas)*2]; %[1,#elemetos]
        %% escalas de Dibujo la estructura y su deformada
        esc_def    = (app.Escarladeformada.Value);          % escalamiento de la deformada
        esc_faxial = 1/(app.Escarlacargaaxial.Value);      % escalamiento del diagrama de axiales
        esc_V      = 1/(app.Escarlacortantes.Value);       % escalamiento del diagrama de cortantes
        esc_M      = 1/(app.EscarlaMomentos.Value);      % escalamiento del diagrama de momentos
        fesct=max(max(xyc))*11/16*1;%% escala textos
        fesc=max(max(xyc))*0.4/16;%% escala apoyos
        [qe_glob,ad,Kdd,Mdd,GLe,d,elementos,nudos,xye,MMexdd,T,Ke,ang,AEe,EIe,Ace,Le,P,iteraciones,ite,puntos_graficas]=porticos(EG,mat_elem,xyc,ninf,sec,sec_ele,elemento_carga,nudo_carga,tipo_apoyo,tipo_conti,nudo_resorte,kw,lalb,PD,esc_def,esc_faxial,esc_V,esc_M,fesct,fesc);     
        
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.VigasPanel = uipanel(app.UIFigure);
            app.VigasPanel.Title = 'Vigas';
            app.BfmEditFieldLabel = uilabel(app.VigasPanel);
            app.BfmEditFieldLabel.Text = 'Bf(m)';
            app.Bfv = uieditfield(app.VigasPanel, 'numeric');
            app.Bfv.Value = 0.4;
            app.dmEditFieldLabel = uilabel(app.VigasPanel);
            app.dmEditFieldLabel.Text = 'd(m)';
            app.dvv = uieditfield(app.VigasPanel, 'numeric');
            app.dvv.Value = 0.5;
            app.EMPaEditFieldLabel = uilabel(app.VigasPanel);
            app.EMPaEditFieldLabel.Text = 'E(MPa)';
            app.Ecv = uieditfield(app.VigasPanel, 'numeric');
            app.Ecv.ValueDisplayFormat = '%.4f';
            app.Ecv.Value = 24870;
            app.ColumnasPanel = uipanel(app.UIFigure);
            app.ColumnasPanel.Title = 'Columnas';
            app.BfmEditField_2Label = uilabel(app.ColumnasPanel);
            app.BfmEditField_2Label.Text = 'Bf(m)';
            app.Bfc = uieditfield(app.ColumnasPanel, 'numeric');
            app.Bfc.Value = 0.4;
            app.dmEditField_2Label = uilabel(app.ColumnasPanel);
            app.dmEditField_2Label.Text = 'd(m)';
            app.dcc = uieditfield(app.ColumnasPanel, 'numeric');
            app.dcc.Value = 0.5;
            app.EMPaEditFieldLabel_2 = uilabel(app.ColumnasPanel);
            app.EMPaEditFieldLabel_2.Text = 'E(MPa)';
            app.Ecc = uieditfield(app.ColumnasPanel, 'numeric');
            app.Ecc.ValueDisplayFormat = '%.4f';
            app.Ecc.Value = 24870;
            app.CargaspuntualesPanel = uipanel(app.UIFigure);
            app.CargaspuntualesPanel.Title = 'Cargas puntuales';
            app.NudosCargaHorizontalEditFieldLabel = uilabel(app.CargaspuntualesPanel);
            app.NudosCargaHorizontalEditFieldLabel.Text = 'Nudos Carga Horizontal';
            app.NudosCargaHorizontal = uieditfield(app.CargaspuntualesPanel, 'text');
            app.NudosCargaHorizontal.Value = '[2,3,4]';
            app.CargaskNEditFieldLabel = uilabel(app.CargaspuntualesPanel);
            app.CargaskNEditFieldLabel.Text = 'Cargas (kN)';
            app.CargasX = uieditfield(app.CargaspuntualesPanel, 'text');
            app.CargasX.Value = '[30,30,30]';
            app.NudosCargaVertivalEditFieldLabel = uilabel(app.CargaspuntualesPanel);
            app.NudosCargaVertivalEditFieldLabel.Text = 'Nudos Carga Vertival';
            app.NudosCargaVertival = uieditfield(app.CargaspuntualesPanel, 'text');
            app.NudosCargaVertival.Value = '[2,3,4]';
            app.CargaskNEditField_2Label = uilabel(app.CargaspuntualesPanel);
            app.CargaskNEditField_2Label.Text = 'Cargas (kN)';
            app.CargasY = uieditfield(app.CargaspuntualesPanel, 'text');
            app.CargasY.Value = '[30,30,30]';
            app.ApoyosPanel = uipanel(app.UIFigure);
            app.ApoyosPanel.Title = 'Apoyos';
            app.NudosapoyosEditFieldLabel = uilabel(app.ApoyosPanel);
            app.NudosapoyosEditFieldLabel.Text = 'Nudos apoyos';
            app.Nudosapoyos = uieditfield(app.ApoyosPanel, 'text');
            app.Nudosapoyos.Value = '[1,5,9,13]';
            app.tipodeapoyosEditFieldLabel = uilabel(app.ApoyosPanel);
            app.tipodeapoyosEditFieldLabel.Text = 'tipo de apoyos';
            app.tipodeapoyos = uieditfield(app.ApoyosPanel, 'text');
            app.tipodeapoyos.Value = '[123,123,123,123]';
            app.GeometriaButton = uibutton(app.UIFigure, 'push');
            app.GeometriaButton.ButtonPushedFcn = createCallbackFcn(app, @GeometriaButtonPushed, true);
            app.GeometriaButton.Text = 'Geometria';
            app.GeometriaGrillaPanel = uipanel(app.UIFigure);
            app.GeometriaGrillaPanel.Title = 'Geometria Grilla';
            app.datosY = uitable(app.GeometriaGrillaPanel);
            app.datosY.ColumnName = {'Sy(m)'};
            app.datosY.RowName = {};
            app.datosY.ColumnEditable = true;
            app.datosX = uitable(app.GeometriaGrillaPanel);
            app.datosX.ColumnName = {'Sx(m)'};
            app.datosX.RowName = {};
            app.datosX.ColumnEditable = true;
            app.Actualizar = uibutton(app.GeometriaGrillaPanel, 'push');
            app.Actualizar.ButtonPushedFcn = createCallbackFcn(app, @ActualizarPushed, true);
            app.Actualizar.Text = 'Actualizar';
            app.datosXSpinnerLabel = uilabel(app.GeometriaGrillaPanel);
            app.datosXSpinnerLabel.Text = 'datos X';
            app.datosXSp = uispinner(app.GeometriaGrillaPanel);
            app.datosXSp.Value = 3;
            app.datosYSpinnerLabel = uilabel(app.GeometriaGrillaPanel);
            app.datosYSpinnerLabel.Text = 'datos Y';
            app.datosYSp = uispinner(app.GeometriaGrillaPanel);
            app.datosYSp.Value = 3;
            app.CalculoButton = uibutton(app.UIFigure, 'state');
            app.CalculoButton.ValueChangedFcn = createCallbackFcn(app, @CalculoButtonValueChanged, true);
            app.CalculoButton.Text = 'Calculo';
            app.EscalasPanel = uipanel(app.UIFigure);
            app.EscalasPanel.Title = 'Escalas';
            app.EscarlacargaaxialEditFieldLabel = uilabel(app.EscalasPanel);
            app.EscarlacargaaxialEditFieldLabel.Text = 'Escarla carga axial';
            app.Escarlacargaaxial = uieditfield(app.EscalasPanel, 'numeric');
            app.Escarlacargaaxial.Value = 100;
            app.EscarlaMomentosEditFieldLabel = uilabel(app.EscalasPanel);
            app.EscarlaMomentosEditFieldLabel.Text = 'Escarla Momentos';
            app.EscarlaMomentos = uieditfield(app.EscalasPanel, 'numeric');
            app.EscarlaMomentos.Value = 70;
            app.EscarlacortantesEditFieldLabel = uilabel(app.EscalasPanel);
            app.EscarlacortantesEditFieldLabel.Text = 'Escarla cortantes';
            app.Escarlacortantes = uieditfield(app.EscalasPanel, 'numeric');
            app.Escarlacortantes.Value = 60;
            app.EscarladeformadaEditFieldLabel = uilabel(app.EscalasPanel);
            app.EscarladeformadaEditFieldLabel.Text = 'Escarla deformada';
            app.Escarladeformada = uieditfield(app.EscalasPanel, 'numeric');
            app.Escarladeformada.Value = 1;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = poticos

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end